classdef cut_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CutSignalButton             matlab.ui.control.Button
        EndTimesEditField           matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel      matlab.ui.control.Label
        InitialTimesEditField       matlab.ui.control.NumericEditField
        InitialTimesEditFieldLabel  matlab.ui.control.Label
        NewLimitsofECGSignalLabel   matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InitialTimesEditField
        function InitialTimesEditFieldValueChanged(app, event)
            app.EndTimesEditField.Limits=[app.InitialTimesEditField.Value,inf];
        end

        % Value changed function: EndTimesEditField
        function EndTimesEditFieldValueChanged(app, event)
            app.InitialTimesEditField.Limits=[0,app.EndTimesEditField.Value]; 
        end

        % Button pushed function: CutSignalButton
        function CutSignalButtonPushed(app, event)
            cut_Signal=[app.InitialTimesEditField.Value,app.EndTimesEditField.Value];
            save("cutSignal.mat","cut_Signal")
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'login.png');
            app.NewLimitsofECGSignalLabel = uilabel(app.UIFigure);
            app.NewLimitsofECGSignalLabel.Text = 'New Limits of ECG Signal';
            app.InitialTimesEditFieldLabel = uilabel(app.UIFigure);
            app.InitialTimesEditFieldLabel.Text = 'Initial Time (s)';
            app.InitialTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialTimesEditField.Limits = [0 Inf];
            app.InitialTimesEditField.ValueChangedFcn = createCallbackFcn(app, @InitialTimesEditFieldValueChanged, true);
            app.EndTimesEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EndTimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditFieldValueChanged, true);
            app.EndTimesEditField.Value = 60;
            app.CutSignalButton = uibutton(app.UIFigure, 'push');
            app.CutSignalButton.ButtonPushedFcn = createCallbackFcn(app, @CutSignalButtonPushed, true);
            app.CutSignalButton.Text = 'Cut Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cut_signal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end