classdef ecg_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGAppUIFigure                  matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        reprocessing                    matlab.ui.container.toolbar.PushTool
        cutSignal                       matlab.ui.container.toolbar.PushTool
        preProcessing                   matlab.ui.container.toolbar.PushTool
        peaksDetection                  matlab.ui.container.toolbar.PushTool
        allBeat                         matlab.ui.container.toolbar.PushTool
        averageBeat                     matlab.ui.container.toolbar.ToggleTool
        BeatsperMinute                  matlab.ui.container.toolbar.PushTool
        allMetrics                      matlab.ui.container.toolbar.PushTool
        frenquencyAnalysis              matlab.ui.container.toolbar.PushTool
        patientDiagnostic               matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        AcquisitionsListLabel           matlab.ui.control.Label
        AquisitionList                  matlab.ui.control.ListBox
        SaveNewInfoButton               matlab.ui.control.Button
        DateofBirthEditField            matlab.ui.control.EditField
        DateofBirthEditFieldLabel       matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.EditField
        AgeEditFieldLabel               matlab.ui.control.Label
        PatientInformationLabel         matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        LoadAcquisitionButton           matlab.ui.control.Button
        NewAcquisitionButton            matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        RudimentaryDiagnosticsLabel     matlab.ui.control.Label
        diagnostic_text                 matlab.ui.control.TextArea
        postprocessing_text             matlab.ui.control.TextArea
        PostprocessingInformationLabel  matlab.ui.control.Label
        graph                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        PatientInfo=struct;
        ECG_Aquisitions=struct;
        ECG_Info=struct;
        ECG_Diagnostics=struct;
        idx=0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(NaN,NaN,'r','Parent',app.graph)
            title(app.graph,'ECG Signal')
            xlabel(app.graph,'X')
            ylabel(app.graph,'Y')
            loadGraphSampling=[];
            aquisitionData=[];
            new_preprocess=struct;
            save("loadGraphSampling.mat","loadGraphSampling");
            save("newAquisitionsSettings.mat","aquisitionData");
            save("new_preprocess.mat","new_preprocess");

            load("AllPatients.mat","AllPatients");
            for i=1:numel(fieldnames(AllPatients))
                if AllPatients.("Patient"+i).PatientInfo.Login==1
                    app.PatientInfo=AllPatients.("Patient"+i).PatientInfo;
                    if isfield(AllPatients.("Patient"+i), "ECG_Aquisitions")
                        app.ECG_Aquisitions=AllPatients.("Patient"+i).ECG_Aquisitions;
                    end
                    if isfield(AllPatients.("Patient"+i), "ECG_Info")
                        app.ECG_Info=AllPatients.("Patient"+i).ECG_Info;
                    end
                    if isfield(AllPatients.("Patient"+i), "ECG_Diagnostics")
                        app.ECG_Diagnostics=AllPatients.("Patient"+i).ECG_Diagnostics;
                    end
                    break
                end
            end
            app.idx=i;
            app.NameEditField.Value=app.PatientInfo.Name;
            if isfield(app.PatientInfo, "Age")
                app.AgeEditField.Value=app.PatientInfo.Age;
            end
            if isfield(app.PatientInfo, "Sex")
                app.SexEditField.Value=app.PatientInfo.Sex;
            end
            if isfield(app.PatientInfo, "DateofBirth")
                app.DateofBirthEditField.Value=app.PatientInfo.DateofBirth;
            end

            if isfield(app.ECG_Aquisitions, "Aquisition1")
                newList=strings(1,numel(fieldnames(app.ECG_Aquisitions)));
                for j=1:numel(fieldnames(app.ECG_Aquisitions))
                    listName="Acquisition "+j+" - "+datestr(app.ECG_Aquisitions.("Aquisition"+j).AquisitionDate);
                    newList(j)=listName;
                end
                app.AquisitionList.Items=newList;
            else
                app.AquisitionList.Items="";
            end

        end

        % Button pushed function: LoadAcquisitionButton
        function LoadAcquisitionButtonPushed(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        ECG=app.ECG_Aquisitions.("Aquisition"+i).ECG;
                        time=app.ECG_Aquisitions.("Aquisition"+i).Time-app.ECG_Aquisitions.("Aquisition"+i).Time(1);
                        samplingRate=app.ECG_Aquisitions.("Aquisition"+i).AquisitionFreq;
                        aquisitionTime=app.ECG_Aquisitions.("Aquisition"+i).AquisitionTime;
                        break
                    end
                end

                waitfor(load_settings);
                load("loadGraphSampling.mat","loadGraphSampling")
                if isempty(loadGraphSampling)
                    loadGraphSampling=[0,time(end)];
                elseif loadGraphSampling(2)>time(end)
                    loadGraphSampling(2)=time(end);
                end
                app.postprocessing_text.Value="Aquisition Sample Rate: "+samplingRate+" Hz Acquisition Time: "+aquisitionTime+" minutes";
                app.diagnostic_text.Value="";
                save("loadGraphSampling.mat","loadGraphSampling")
                cla(app.graph, 'reset');
                plot(time,ECG,'r','Parent',app.graph)
                title(app.graph,'ECG Signal - Raw  Signal')
                xlabel(app.graph,"Time (s)")
                ylabel(app.graph,"Potential (mV)")
                xlim(app.graph,loadGraphSampling)
            else
                warndlg("Don't exist saved aquisitions! Try start a New Aquisition!");
            end
        end

        % Button pushed function: NewAcquisitionButton
        function NewAcquisitionButtonPushed(app, event)
            waitfor(how2use);
            load("newAquisitionsSettings.mat","aquisitionData")
            plot(NaN,NaN,'r','Parent',app.graph)
            title(app.graph,'ECG Signal - Raw  Signal')
            xlabel(app.graph,"Time (s)")
            ylabel(app.graph,"Potential (mV)")
            try
                app.postprocessing_text.Value="Sampling Rate: "+aquisitionData(2)+" Hz      Aquisition Time: "+aquisitionData(1)+" minutes";
                app.diagnostic_text.Value="";
                serialCommunication(app.idx,aquisitionData(1),aquisitionData(2),aquisitionData(3),app.graph);
            catch
                load("AllPatients.mat","AllPatients")
                AllPatients.("Patient"+app.idx).ECG_Aquisitions = rmfield(AllPatients.("Patient"+app.idx).ECG_Aquisitions,"Aquisition"+numel(fieldnames(AllPatients.("Patient"+app.idx).ECG_Aquisitions)));
                if numel(fieldnames(AllPatients.("Patient"+app.idx).ECG_Info))==numel(fieldnames(AllPatients.("Patient"+app.idx).ECG_Aquisitions))+1
                    AllPatients.("Patient"+app.idx).ECG_Info = rmfield(AllPatients.("Patient"+app.idx).ECG_Info,"Aquisition"+numel(fieldnames(AllPatients.("Patient"+app.idx).ECG_Info)));
                end
                if numel(fieldnames(AllPatients.("Patient"+app.idx).ECG_Diagnostics))==numel(fieldnames(AllPatients.("Patient"+app.idx).ECG_Aquisitions))+1
                    AllPatients.("Patient"+app.idx).ECG_Diagnostics = rmfield(AllPatients.("Patient"+app.idx).ECG_Diagnostics,"Aquisition"+numel(fieldnames(AllPatients.("Patient"+app.idx).ECG_Diagnostics)));
                end

                save("AllPatients.mat","AllPatients")
                warndlg("Error with the New Aquisition! Try again, please!");
            end
        end

        % Clicked callback: reprocessing
        function reprocessingClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        all_ecg=app.ECG_Aquisitions.("Aquisition"+i).ECG;
                        fs=app.ECG_Aquisitions.("Aquisition"+i).AquisitionFreq;
                        all_time=app.ECG_Aquisitions.("Aquisition"+i).Time-app.ECG_Aquisitions.("Aquisition"+i).Time(1);
                        break
                    end
                end

                try
                    waitfor(new_processing);
                    load("new_preprocess.mat","new_preprocess");
                    load("AllPatients.mat","AllPatients");
                    pacient_ID=app.idx; n_aquisitions=i;
                    [ecg_processed,~,~,ecg_energy,~,Peaks,beats_min,all_Beats,avg_Beat,beat_points,RR_interval,metrics]=ecg_processing(all_time,all_ecg,fs,new_preprocess.movingaverage,new_preprocess.lowpass,new_preprocess.bandpass,new_preprocess.timewindow);
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Processed=ecg_processed;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Energy=ecg_energy;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Peaks=Peaks;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Beats_minute=beats_min;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_All_Beats=all_Beats;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Avg_Beat=avg_Beat;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Beat_Points=beat_points;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_RR_Intervals=RR_interval;
                    AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Metrics=metrics;
                    save("AllPatients.mat","AllPatients");
                    diagnostic(pacient_ID,n_aquisitions)
                    app.ECG_Info=AllPatients.("Patient"+pacient_ID).ECG_Info;
                    app.ECG_Diagnostics=AllPatients.("Patient"+pacient_ID).ECG_Diagnostics;
                catch
                    warndlg("Error with the Cut ECG Signal Option! Try again, please!");
                end
            end
        end

        % Clicked callback: cutSignal
        function cutSignalClicked(app, event)
             if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        all_ecg=app.ECG_Aquisitions.("Aquisition"+i).ECG;
                        all_time=app.ECG_Aquisitions.("Aquisition"+i).Time-app.ECG_Aquisitions.("Aquisition"+i).Time(1);
                        fs=app.ECG_Aquisitions.("Aquisition"+i).AquisitionFreq;
                        break
                    end
                end

                try
                waitfor(cut_signal);
                pacient_ID=app.idx; n_aquisitions=i;
                load("cutSignal.mat","cut_Signal");
                load("AllPatients.mat","AllPatients");
                new_time=all_time(all_time<=cut_Signal(2));
                new_time=new_time(new_time>=cut_Signal(1));
                new_ecg=all_ecg(nonzeros((1:length(all_ecg)).*(all_time<=cut_Signal(2)).*(all_time>=cut_Signal(1))));
                AllPatients.("Patient"+pacient_ID).ECG_Aquisitions.("Aquisition"+n_aquisitions).ECG=new_ecg;
                AllPatients.("Patient"+pacient_ID).ECG_Aquisitions.("Aquisition"+n_aquisitions).Time=new_time;
                AllPatients.("Patient"+pacient_ID).ECG_Aquisitions.("Aquisition"+n_aquisitions).AquisitionTime=(cut_Signal(2)-cut_Signal(1))/60;

                [ecg_processed,~,~,ecg_energy,~,Peaks,beats_min,all_Beats,avg_Beat,beat_points,RR_interval,metrics]=ecg_processing(new_time,new_ecg,fs,2,30,[5,25],0.2);
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Processed=ecg_processed;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Energy=ecg_energy;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Peaks=Peaks;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Beats_minute=beats_min;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_All_Beats=all_Beats;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Avg_Beat=avg_Beat;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Beat_Points=beat_points;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_RR_Intervals=RR_interval;
                AllPatients.("Patient"+pacient_ID).ECG_Info.("Aquisition"+n_aquisitions).ECG_Metrics=metrics;
                save("AllPatients.mat","AllPatients");
                % Small Diagnostics
                diagnostic(pacient_ID,n_aquisitions)

                app.ECG_Aquisitions=AllPatients.("Patient"+pacient_ID).ECG_Aquisitions;
                app.ECG_Info=AllPatients.("Patient"+pacient_ID).ECG_Info;
                app.ECG_Diagnostics=AllPatients.("Patient"+pacient_ID).ECG_Diagnostics;
                catch
                    warndlg("Error with the Cut ECG Signal Option! Try again, please!");
                end
             end
        end

        % Clicked callback: preProcessing
        function preProcessingClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        ECG_PreProcessed=app.ECG_Info.("Aquisition"+i).ECG_Processed;
                        time=app.ECG_Aquisitions.("Aquisition"+i).Time-app.ECG_Aquisitions.("Aquisition"+i).Time(1);
                        break
                    end
                end

                cla(app.graph, 'reset');
                plot(time(1:length(ECG_PreProcessed)),ECG_PreProcessed,'r','Parent',app.graph)
                title(app.graph,'ECG Signal - Noise Reduction and Base Line Remove')
                xlabel(app.graph,"Time (s)")
                ylabel(app.graph,"Potential (mV)")
                load("loadGraphSampling.mat","loadGraphSampling")
                if isempty(loadGraphSampling)
                    loadGraphSampling=[0,time(end)];
                elseif loadGraphSampling(2)>time(end)
                    loadGraphSampling(2)=time(end);
                end
                xlim(app.graph,loadGraphSampling)
                app.postprocessing_text.Value="Signal after removing the baseline with a Moving-Average filter. Also, a Low-Pass filter with a certain cutoff frequency was applied.";
                app.diagnostic_text.Value="";
            end
        end

        % Clicked callback: peaksDetection
        function peaksDetectionClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        time=app.ECG_Aquisitions.("Aquisition"+i).Time-app.ECG_Aquisitions.("Aquisition"+i).Time(1);
                        ECG_Processed=app.ECG_Info.("Aquisition"+i).ECG_Processed;
                        ECG_R_peaks=app.ECG_Info.("Aquisition"+i).ECG_Peaks.R_peaks;
                        ECG_Q_peaks=app.ECG_Info.("Aquisition"+i).ECG_Peaks.Q_peaks;
                        ECG_S_peaks=app.ECG_Info.("Aquisition"+i).ECG_Peaks.S_peaks;
                        ECG_P_peaks=app.ECG_Info.("Aquisition"+i).ECG_Peaks.P_peaks;
                        ECG_T_peaks=app.ECG_Info.("Aquisition"+i).ECG_Peaks.T_peaks;
                        break
                    end
                end
                cla(app.graph, 'reset');
                plot(time(1:length(ECG_Processed)),ECG_Processed,'r','Parent',app.graph)
                hold(app.graph,"on")
                scatter(app.graph,time(ECG_R_peaks), ECG_Processed(ECG_R_peaks),100, '.','MarkerEdgeColor',"#0072BD",'LineWidth',2);
                scatter(app.graph,time(ECG_R_peaks), ECG_Processed(ECG_R_peaks),100, 's','MarkerEdgeColor',"#0072BD",'LineWidth',2);
                scatter(app.graph,time(ECG_Q_peaks), ECG_Processed(ECG_Q_peaks),100, '.','MarkerEdgeColor',"#77AC30",'LineWidth',2);
                scatter(app.graph,time(ECG_Q_peaks), ECG_Processed(ECG_Q_peaks),100, 's','MarkerEdgeColor',"#77AC30",'LineWidth',2);
                scatter(app.graph,time(ECG_S_peaks), ECG_Processed(ECG_S_peaks),100, '.','MarkerEdgeColor',"#EDB120",'LineWidth',2);
                scatter(app.graph,time(ECG_S_peaks), ECG_Processed(ECG_S_peaks),100, 's','MarkerEdgeColor',"#EDB120",'LineWidth',2);
                scatter(app.graph,time(ECG_P_peaks), ECG_Processed(ECG_P_peaks),100, '.','MarkerEdgeColor',"#D95319",'LineWidth',2);
                scatter(app.graph,time(ECG_P_peaks), ECG_Processed(ECG_P_peaks),100, 's','MarkerEdgeColor',"#D95319",'LineWidth',2);
                scatter(app.graph,time(ECG_T_peaks), ECG_Processed(ECG_T_peaks),100, '.','MarkerEdgeColor',"#7E2F8E",'LineWidth',2);
                scatter(app.graph,time(ECG_T_peaks), ECG_Processed(ECG_T_peaks),100, 's','MarkerEdgeColor',"#7E2F8E",'LineWidth',2);
                hold(app.graph,"off")
                title(app.graph,'ECG Signal - Peaks Detection')
                xlabel("Time (s)",'Parent',app.graph)
                ylabel("Potential (mV)",'Parent',app.graph)
                load("loadGraphSampling.mat","loadGraphSampling")
                if isempty(loadGraphSampling)
                    loadGraphSampling=[0,time(end)];
                elseif loadGraphSampling(2)>time(end)
                    loadGraphSampling(2)=time(end);
                end
                xlim(app.graph,loadGraphSampling)
                app.postprocessing_text.Value="Signal with identification of ECG peaks. This algorithm allowed to distinguish mainly the QRS complex:      P Peaks - Orange;                       Q Peaks - Yellow;           Peaks R - Blue;                         Peaks S - Green;            Peaks T - Purple.";
                app.diagnostic_text.Value="";
            end
        end

        % Clicked callback: averageBeat
        function averageBeatClicked(app, event)
            app.averageBeat.State="off";
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        Avg_Beat=app.ECG_Info.("Aquisition"+i).ECG_Avg_Beat;
                        beat_points=app.ECG_Info.("Aquisition"+i).ECG_Beat_Points;
                        fs=app.ECG_Aquisitions.("Aquisition"+i).AquisitionFreq;
                        break
                    end
                end

                cla(app.graph, 'reset');
                plot(0:1/fs:beat_points*(1/fs),Avg_Beat,'Color','r','Parent',app.graph);
                title(app.graph,'ECG Signal - Average Beat')
                xlabel("Time (s)",'Parent',app.graph)
                ylabel("Potential (mV)",'Parent',app.graph)
                xlim(app.graph,[0,beat_points*(1/fs)])
                ylim(app.graph,[min(Avg_Beat)-50,max(Avg_Beat)+50])
                app.postprocessing_text.Value="This feature allows you to view the approximation of the Average Beat. Note the fact that we used the average of all individual beats (with some temporal variation between them), causing some ECG Peaks to be dropped.";
                app.diagnostic_text.Value="";
            end

        end

        % Clicked callback: BeatsperMinute
        function BeatsperMinuteClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        ECG_Beats_minute=app.ECG_Info.("Aquisition"+i).ECG_Beats_minute;
                        break
                    end
                end
                app.postprocessing_text.Value="With this algorithm, it was possible to determine the exact number of beats during the exam time. After that it was easy to convert to beats per minute.            BPM: "+round(ECG_Beats_minute);
                app.diagnostic_text.Value="";
            end
        end

        % Clicked callback: allMetrics
        function allMetricsClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        ECG_R_peaks=app.ECG_Info.("Aquisition"+i).ECG_Peaks.R_peaks;
                        ECG_RR_Intervals=app.ECG_Info.("Aquisition"+i).ECG_RR_Intervals;
                        time=app.ECG_Aquisitions.("Aquisition"+i).Time-app.ECG_Aquisitions.("Aquisition"+i).Time(1);
                        metrics=app.ECG_Info.("Aquisition"+i).ECG_Metrics;
                        break
                    end
                end

                cla(app.graph, 'reset');
                plot(time(ECG_R_peaks(2:end)),ECG_RR_Intervals,'r--','LineWidth',0.5,'Parent',app.graph)
                hold(app.graph,"on")
                scatter(app.graph,time(ECG_R_peaks(2:end)),ECG_RR_Intervals,'o','filled','r','LineWidth',2)
                yline(mean(ECG_RR_Intervals),'k:','LineWidth',2,'Parent',app.graph)
                hold(app.graph,"off")
                title(app.graph,'ECG Signal - RR Intervals Variation')
                ylabel("NN Intervals Time (ms)",'Parent',app.graph)
                xlabel("Time (s)",'Parent',app.graph)
                app.postprocessing_text.Value="In this function, it was possible to study the Heart Rate Variability (HRV). Some HRV Metrics:                      Mean > "+round(metrics.MEAN,2)+" s                       SDNN > "+round(metrics.SDNN,2)+" ms             SDSD > "+round(metrics.SDSD,2)+" ms             RMSSD > "+round(metrics.RMSSD,2)+" ms           NN50 (pNN50) > "+metrics.NN50+" ("+round(metrics.pNN50,2)+"%) RaLH > "+round(metrics.RaLH,2);
                app.diagnostic_text.Value="";
            end
        end

        % Clicked callback: frenquencyAnalysis
        function frenquencyAnalysisClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        ECG_RR_Intervals=app.ECG_Info.("Aquisition"+i).ECG_RR_Intervals;
                        fs=app.ECG_Aquisitions.("Aquisition"+i).AquisitionFreq;
                        break
                    end
                end
                ECG_RR_Intervals=ECG_RR_Intervals*1000;
                [pxx, f] = pburg(ECG_RR_Intervals,4,2^16,fs);
                power_LF = pxx.*(f >= 0.04 & f < 0.15);
                power_HF = pxx.*(f >= 0.15 & f < 0.4);
                cla(app.graph, 'reset');
                set(app.graph, "Layer", "top")
                plot(f,abs(pxx),'r','Parent',app.graph)
                hold(app.graph,"on")
                rectangle(app.graph,Position=[0,app.graph.YLim(1),0.04,app.graph.YLim(2)], FaceColor=[1 0 0 0.1], EdgeColor=[1 0 0 0.1])
                rectangle(app.graph,Position=[0.04,app.graph.YLim(1),0.15-0.04,app.graph.YLim(2)], FaceColor=[0 1 0 0.1], EdgeColor=[0 1 0 0.1])
                rectangle(app.graph,Position=[0.15,app.graph.YLim(1),0.4-0.15,app.graph.YLim(2)], FaceColor=[0 0 1 0.1], EdgeColor=[0 0 1 0.1])
                set(app.graph, "Layer", "top")
                plot(f,abs(power_LF),'g','LineWidth',1.5,'Parent',app.graph)
                set(app.graph, "Layer", "top")
                plot(f,abs(power_HF),'b','LineWidth',1.5,'Parent',app.graph)
                xline(0.04,'k:','LineWidth',0.5,'Parent',app.graph)
                xline(0.15,'k:','LineWidth',0.5,'Parent',app.graph)
                xline(0.4,'k:','LineWidth',0.5,'Parent',app.graph)
                xlim(app.graph,[0,0.5])
                hold(app.graph,"off")
                title(app.graph,'ECG Signal - Power Spectral Analysis')
                xlabel("Frequency (Hz)",'Parent',app.graph)
                ylabel("ms^2",'Parent',app.graph)
                app.postprocessing_text.Value="With this functionality, it is possible to see the behavior of Power Spectral Density (PSD), and thus understand the relationship between the action of the sympathetic and parasympathetic nervous systems.";
                app.diagnostic_text.Value="";
            end
        end

        % Clicked callback: allBeat
        function allBeatClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        ECG_Processed=app.ECG_Info.("Aquisition"+i).ECG_Processed;
                        all_beats=app.ECG_Info.("Aquisition"+i).ECG_All_Beats;
                        beat_points=app.ECG_Info.("Aquisition"+i).ECG_Beat_Points;
                        fs=app.ECG_Aquisitions.("Aquisition"+i).AquisitionFreq;
                        break
                    end
                end

                cla(app.graph, 'reset');
                plot(0:1/fs:beat_points*(1/fs),ECG_Processed(all_beats(1,:)),'Parent',app.graph)
                hold(app.graph,"on")
                for i=2:size(all_beats,1)
                    plot(0:1/fs:beat_points*(1/fs),ECG_Processed(all_beats(i,:)),'Parent',app.graph)
                end
                hold(app.graph,"off")
                title(app.graph,'ECG Signal - All Beats')
                xlabel("Time (s)",'Parent',app.graph)
                ylabel("Potential (mV)",'Parent',app.graph)
                xlim(app.graph,[0,beat_points*(1/fs)])
                app.postprocessing_text.Value="All the individual beats were superimposed, so that it was possible to perceive the variability between the various heart beats.";
                app.diagnostic_text.Value="";
            end
        end

        % Clicked callback: patientDiagnostic
        function patientDiagnosticClicked(app, event)
            if app.AquisitionList.Items~=""
                for i=1:length(app.AquisitionList.Items)
                    if strcmp(app.AquisitionList.Items(i),app.AquisitionList.Value)
                        arrhythmia=app.ECG_Diagnostics.("Aquisition"+i).Arrhythmia;
                        ectopic=app.ECG_Diagnostics.("Aquisition"+i).Ectopic_Beats;
                        break
                    end
                end
                app.diagnostic_text.Value="This diagnosis is not medically official as it is based only on threshold values.                Arrhythmia > "+arrhythmia+" Ectopic_Beats > "+ectopic;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ECGAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.PatientInfo.Name=app.NameEditField.Value;
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            app.PatientInfo.Age=app.AgeEditField.Value;
        end

        % Value changed function: SexEditField
        function SexEditFieldValueChanged(app, event)
            app.PatientInfo.Sex=app.SexEditField.Value;
        end

        % Value changed function: DateofBirthEditField
        function DateofBirthEditFieldValueChanged(app, event)
            app.PatientInfo.DateofBirth=app.DateofBirthEditField.Value;
        end

        % Button pushed function: SaveNewInfoButton
        function SaveNewInfoButtonValueChanged(app, event)
            load("AllPatients.mat","AllPatients");
            AllPatients.("Patient"+app.idx).PatientInfo=app.PatientInfo;
            save("AllPatients.mat","AllPatients");
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.idx~=0
                load("AllPatients.mat","AllPatients");
                app.ECG_Aquisitions=AllPatients.("Patient"+app.idx).ECG_Aquisitions;
                app.ECG_Info=AllPatients.("Patient"+app.idx).ECG_Info;
                app.ECG_Diagnostics=AllPatients.("Patient"+app.idx).ECG_Diagnostics;
                if isfield(app.ECG_Aquisitions, "Aquisition1")
                    newList=strings(1,numel(fieldnames(app.ECG_Aquisitions)));
                    for j=1:numel(fieldnames(app.ECG_Aquisitions))
                        listName="Aquisition "+j+" - "+datestr(app.ECG_Aquisitions.("Aquisition"+j).AquisitionDate);
                        newList(j)=listName;
                    end
                    app.AquisitionList.Items=newList;
                else
                    app.AquisitionList.Items="";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECGAppUIFigure = uifigure('Visible', 'off');
            app.ECGAppUIFigure.Name = 'ECG App';
            app.ECGAppUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'login.png');
            app.ECGAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.ECGAppUIFigure);
            app.reprocessing = uipushtool(app.Toolbar);
            app.reprocessing.ClickedCallback = createCallbackFcn(app, @reprocessingClicked, true);
            app.reprocessing.Icon = fullfile(pathToMLAPP, 'images', 'reprocessing.png');
            app.cutSignal = uipushtool(app.Toolbar);
            app.cutSignal.ClickedCallback = createCallbackFcn(app, @cutSignalClicked, true);
            app.cutSignal.Icon = fullfile(pathToMLAPP, 'images', 'cut.png');
            app.preProcessing = uipushtool(app.Toolbar);
            app.preProcessing.ClickedCallback = createCallbackFcn(app, @preProcessingClicked, true);
            app.preProcessing.Icon = fullfile(pathToMLAPP, 'images', 'noiseReduction.png');
            app.preProcessing.Separator = 'on';
            app.peaksDetection = uipushtool(app.Toolbar);
            app.peaksDetection.ClickedCallback = createCallbackFcn(app, @peaksDetectionClicked, true);
            app.peaksDetection.Icon = fullfile(pathToMLAPP, 'images', 'peaksR.png');
            app.allBeat = uipushtool(app.Toolbar);
            app.allBeat.ClickedCallback = createCallbackFcn(app, @allBeatClicked, true);
            app.allBeat.Icon = fullfile(pathToMLAPP, 'images', 'all_beats.png');
            app.averageBeat = uitoggletool(app.Toolbar);
            app.averageBeat.ClickedCallback = createCallbackFcn(app, @averageBeatClicked, true);
            app.averageBeat.Icon = fullfile(pathToMLAPP, 'images', 'average.png');
            app.BeatsperMinute = uipushtool(app.Toolbar);
            app.BeatsperMinute.ClickedCallback = createCallbackFcn(app, @BeatsperMinuteClicked, true);
            app.BeatsperMinute.Icon = fullfile(pathToMLAPP, 'images', 'bpm.png');
            app.allMetrics = uipushtool(app.Toolbar);
            app.allMetrics.ClickedCallback = createCallbackFcn(app, @allMetricsClicked, true);
            app.allMetrics.Icon = fullfile(pathToMLAPP, 'images', 'metrics.png');
            app.frenquencyAnalysis = uipushtool(app.Toolbar);
            app.frenquencyAnalysis.ClickedCallback = createCallbackFcn(app, @frenquencyAnalysisClicked, true);
            app.frenquencyAnalysis.Icon = fullfile(pathToMLAPP, 'images', 'frequency.png');
            app.patientDiagnostic = uipushtool(app.Toolbar);
            app.patientDiagnostic.ClickedCallback = createCallbackFcn(app, @patientDiagnosticClicked, true);
            app.patientDiagnostic.Icon = fullfile(pathToMLAPP, 'images', 'diagnostic.png');
            app.patientDiagnostic.Separator = 'on';
            app.GridLayout = uigridlayout(app.ECGAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NameEditFieldLabel = uilabel(app.LeftPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.LeftPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.PatientInformationLabel = uilabel(app.LeftPanel);
            app.PatientInformationLabel.Text = 'Patient Information';
            app.AgeEditFieldLabel = uilabel(app.LeftPanel);
            app.AgeEditFieldLabel.Text = 'Age:';
            app.AgeEditField = uieditfield(app.LeftPanel, 'text');
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.SexEditFieldLabel = uilabel(app.LeftPanel);
            app.SexEditFieldLabel.Text = 'Sex:';
            app.SexEditField = uieditfield(app.LeftPanel, 'text');
            app.SexEditField.ValueChangedFcn = createCallbackFcn(app, @SexEditFieldValueChanged, true);
            app.DateofBirthEditFieldLabel = uilabel(app.LeftPanel);
            app.DateofBirthEditFieldLabel.Text = 'Date of Birth:';
            app.DateofBirthEditField = uieditfield(app.LeftPanel, 'text');
            app.DateofBirthEditField.ValueChangedFcn = createCallbackFcn(app, @DateofBirthEditFieldValueChanged, true);
            app.SaveNewInfoButton = uibutton(app.LeftPanel, 'push');
            app.SaveNewInfoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNewInfoButtonValueChanged, true);
            app.SaveNewInfoButton.Text = 'Save New Info';
            app.AquisitionList = uilistbox(app.LeftPanel);
            app.AquisitionList.Items = {'Aquisition 1', 'Aquisition 2', 'Aquisition 3'};
            app.AquisitionList.Value = 'Aquisition 1';
            app.AcquisitionsListLabel = uilabel(app.LeftPanel);
            app.AcquisitionsListLabel.Text = 'Acquisitions List';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images', 'reset.png');
            app.Button.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.graph = uiaxes(app.RightPanel);
            app.Panel = uipanel(app.RightPanel);
            app.PostprocessingInformationLabel = uilabel(app.Panel);
            app.PostprocessingInformationLabel.Text = 'Post-processing Information';
            app.postprocessing_text = uitextarea(app.Panel);
            app.postprocessing_text.Editable = 'off';
            app.diagnostic_text = uitextarea(app.Panel);
            app.diagnostic_text.Editable = 'off';
            app.RudimentaryDiagnosticsLabel = uilabel(app.Panel);
            app.RudimentaryDiagnosticsLabel.Text = 'Rudimentary Diagnostics';
            app.NewAcquisitionButton = uibutton(app.RightPanel, 'push');
            app.NewAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @NewAcquisitionButtonPushed, true);
            app.NewAcquisitionButton.Text = 'New Acquisition';
            app.LoadAcquisitionButton = uibutton(app.RightPanel, 'push');
            app.LoadAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAcquisitionButtonPushed, true);
            app.LoadAcquisitionButton.Text = 'Load Acquisition';
            % Show the figure after all components are created
            app.ECGAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecg_monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGAppUIFigure)
        end
    end
end