classdef load_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DrawButton                   matlab.ui.control.Button
        EndTimesEditField            matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel       matlab.ui.control.Label
        SignalSamplingDropDown       matlab.ui.control.DropDown
        SignalSamplingDropDownLabel  matlab.ui.control.Label
        InitialTimesEditField        matlab.ui.control.NumericEditField
        InitialTimesEditFieldLabel   matlab.ui.control.Label
        GraphicalSettingsLabel       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SignalSamplingDropDown
        function SignalSamplingDropDownValueChanged(app, event)
            if strcmp(app.SignalSamplingDropDown.Value,"All Signal")
                app.EndTimesEditField.Visible="off";
                app.InitialTimesEditField.Visible="off";
                app.EndTimesEditFieldLabel.Visible="off";
                app.InitialTimesEditFieldLabel.Visible="off";
            else
                app.EndTimesEditField.Visible="on";
                app.InitialTimesEditField.Visible="on";
                app.EndTimesEditFieldLabel.Visible="on";
                app.InitialTimesEditFieldLabel.Visible="on";
            end
        end

        % Value changed function: InitialTimesEditField
        function InitialTimesEditFieldValueChanged(app, event)
            app.EndTimesEditField.Limits=[app.InitialTimesEditField.Value,inf];
        end

        % Value changed function: EndTimesEditField
        function EndTimesEditFieldValueChanged(app, event)
            app.InitialTimesEditField.Limits=[0,app.EndTimesEditField.Value]; 
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            if ~strcmp(app.SignalSamplingDropDown.Value,"All Signal")
                Interval=[app.InitialTimesEditField.Value,app.EndTimesEditField.Value];
            else
                Interval=[];
            end
            load("loadGraphSampling.mat","loadGraphSampling")
            loadGraphSampling=Interval;
            save("loadGraphSampling.mat","loadGraphSampling")
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'login.png');
            app.GraphicalSettingsLabel = uilabel(app.UIFigure);
            app.GraphicalSettingsLabel.Text = 'Graphical Settings';
            app.InitialTimesEditFieldLabel = uilabel(app.UIFigure);
            app.InitialTimesEditFieldLabel.Text = 'Initial Time (s)';
            app.InitialTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialTimesEditField.Limits = [0 Inf];
            app.InitialTimesEditField.ValueChangedFcn = createCallbackFcn(app, @InitialTimesEditFieldValueChanged, true);
            app.SignalSamplingDropDownLabel = uilabel(app.UIFigure);
            app.SignalSamplingDropDownLabel.Text = 'Signal Sampling';
            app.SignalSamplingDropDown = uidropdown(app.UIFigure);
            app.SignalSamplingDropDown.Items = {'Personalized Fractions', 'All Signal'};
            app.SignalSamplingDropDown.Editable = 'on';
            app.SignalSamplingDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalSamplingDropDownValueChanged, true);
            app.SignalSamplingDropDown.Value = 'Personalized Fractions';
            app.EndTimesEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EndTimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditFieldValueChanged, true);
            app.EndTimesEditField.Value = 10;
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = load_settings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end