classdef login_signin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        STARTButton             matlab.ui.control.Button
        LOGINSIGNINLabel        matlab.ui.control.Label
        PassWordEditField       matlab.ui.control.EditField
        PassWordEditFieldLabel  matlab.ui.control.Label
        UserNameEditField       matlab.ui.control.EditField
        UserNameEditFieldLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if (~isempty(app.UserNameEditField.Value) && ~isempty(app.PassWordEditField.Value))
                if isfile("AllPatients.mat")==0
                    AllPatients.Patient1.PatientInfo.Name=app.UserNameEditField.Value;
                    AllPatients.Patient1.PatientInfo.Pass=app.PassWordEditField.Value;
                    AllPatients.Patient1.PatientInfo.Login=true;
                    AllPatients.Patient1.ECG_Aquisitions=struct();
                    AllPatients.Patient1.ECG_Info=struct();
                    AllPatients.Patient1.ECG_Diagnostics=struct();
                    save("AllPatients.mat","AllPatients");
                    ecg_monitor;
                    app.delete;
                else
                    load("AllPatients.mat","AllPatients");
                    flag1=false; flag2=false;
                    for i=1:numel(fieldnames(AllPatients))
                        if (strcmp(AllPatients.("Patient"+i).PatientInfo.Name,app.UserNameEditField.Value)) && (strcmp(AllPatients.("Patient"+i).PatientInfo.Pass,app.PassWordEditField.Value))
                            flag1=true;
                            break
                        elseif ((strcmp(AllPatients.("Patient"+i).PatientInfo.Name,app.UserNameEditField.Value)) && ~(strcmp(AllPatients.("Patient"+i).PatientInfo.Pass,app.PassWordEditField.Value)))||((~strcmp(AllPatients.("Patient"+i).PatientInfo.Name,app.UserNameEditField.Value)) && (strcmp(AllPatients.("Patient"+i).PatientInfo.Pass,app.PassWordEditField.Value)))
                            flag2=true;
                            break
                        end
                    end

                    if flag1==true && flag2==false
                        AllPatients.("Patient"+i).PatientInfo.Name=app.UserNameEditField.Value;
                        AllPatients.("Patient"+i).PatientInfo.Pass=app.PassWordEditField.Value;
                        AllPatients.("Patient"+i).PatientInfo.Login=true;
                        for j=1:numel(fieldnames(AllPatients))
                            if j~=i
                                AllPatients.("Patient"+j).PatientInfo.Login=false;
                            end
                        end
                        save("AllPatients.mat","AllPatients");
                        ecg_monitor;
                        app.delete;

                    elseif flag2==true
                        warndlg("Username or/and password are incorrects! Try again!");

                    else
                        number = numel(fieldnames(AllPatients))+1;
                        AllPatients.("Patient"+number).PatientInfo.Name=app.UserNameEditField.Value;
                        AllPatients.("Patient"+number).PatientInfo.Pass=app.PassWordEditField.Value;
                        AllPatients.("Patient"+number).PatientInfo.Login=true;
                        AllPatients.("Patient"+number).ECG_Aquisitions=struct();
                        AllPatients.("Patient"+number).ECG_Info=struct();
                        AllPatients.("Patient"+number).ECG_Diagnostics=struct();
                        for j=1:numel(fieldnames(AllPatients))
                            if j~=number
                                AllPatients.("Patient"+j).PatientInfo.Login=false;
                            end
                        end
                        save("AllPatients.mat","AllPatients");
                        ecg_monitor;
                        app.delete;
                    end
                end
            else
                warndlg("Username or/and password are incorrects! Try again!");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'login.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'login.png');
            app.UserNameEditFieldLabel = uilabel(app.UIFigure);
            app.UserNameEditFieldLabel.Text = 'User Name:';
            app.UserNameEditField = uieditfield(app.UIFigure, 'text');
            app.PassWordEditFieldLabel = uilabel(app.UIFigure);
            app.PassWordEditFieldLabel.Text = 'PassWord:';
            app.PassWordEditField = uieditfield(app.UIFigure, 'text');
            app.LOGINSIGNINLabel = uilabel(app.UIFigure);
            app.LOGINSIGNINLabel.Text = 'LOGIN / SIGN IN';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login_signin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end