classdef new_aquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RealTimeVisualization         matlab.ui.control.CheckBox
        StartAcquisitionButton        matlab.ui.control.Button
        SamplingRateHz                matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel  matlab.ui.control.Label
        AquisitionTime                matlab.ui.control.NumericEditField
        AquisitionTimeminEditFieldLabel  matlab.ui.control.Label
        NewAcquisitionSettingsLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            aquisitionTime=app.AquisitionTime.Value;
            samplingRate=app.SamplingRateHz.Value;
            realTime=app.RealTimeVisualization.Value;
            aquisitionData=[aquisitionTime,samplingRate,realTime];
            save("newAquisitionsSettings.mat","aquisitionData")
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'login.png');
            app.NewAcquisitionSettingsLabel = uilabel(app.UIFigure);
            app.NewAcquisitionSettingsLabel.Text = 'New Acquisition Settings';
            app.AquisitionTimeminEditFieldLabel = uilabel(app.UIFigure);
            app.AquisitionTimeminEditFieldLabel.Text = 'Aquisition Time (min.)';
            app.AquisitionTime = uieditfield(app.UIFigure, 'numeric');
            app.AquisitionTime.Limits = [0 Inf];
            app.AquisitionTime.Value = 3;
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHz = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateHz.Limits = [150 500];
            app.SamplingRateHz.Value = 300;
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.RealTimeVisualization = uicheckbox(app.UIFigure);
            app.RealTimeVisualization.Text = 'Real Time Visualization';
            app.RealTimeVisualization.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_aquisition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end