classdef new_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ApplyButton                 matlab.ui.control.Button
        timewindow_info             matlab.ui.control.Button
        bandpass_info               matlab.ui.control.Button
        lowpass_info                matlab.ui.control.Button
        movingaverage_info          matlab.ui.control.Button
        BandpassCutofffrequenciesHzEditField_2  matlab.ui.control.NumericEditField
        TimeWindowsecondsEditField  matlab.ui.control.NumericEditField
        TimeWindowsecondsEditFieldLabel  matlab.ui.control.Label
        BandpassCutofffrequenciesHzEditField  matlab.ui.control.NumericEditField
        BandpassCutofffrequenciesHzEditFieldLabel  matlab.ui.control.Label
        LowpassCutofffrequencyHzEditField  matlab.ui.control.NumericEditField
        LowpassCutofffrequencyHzEditFieldLabel  matlab.ui.control.Label
        TimeofmovingavergefiltersecondsEditField  matlab.ui.control.NumericEditField
        TimeofmovingavergefiltersecondsEditFieldLabel  matlab.ui.control.Label
        EditECGPreprocessingSettingsLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: movingaverage_info
        function movingaverage_infoButtonPushed(app, event)
            msgbox("The time in question is related to the width of the Moving-Average Filter for Baseline removal.","Moving-Average Filter");
        end

        % Button pushed function: lowpass_info
        function lowpass_infoButtonPushed(app, event)
            msgbox("Low-pass frequency for noise removal (High frequencies removal).","Low-pass Filter");
        end

        % Button pushed function: bandpass_info
        function bandpass_infoButtonPushed(app, event)
            msgbox("Application of a band-pass filter to isolate the QRS complex.","Band-pass Filter");
        end

        % Button pushed function: timewindow_info
        function timewindow_infoButtonPushed(app, event)
            msgbox("The time in question is related to the width of the Moving-Average Filter for Baseline removal.","Moving Average");
        end

        % Value changed function: BandpassCutofffrequenciesHzEditField
        function BandpassCutofffrequenciesHzEditFieldValueChanged(app, event)
            app.BandpassCutofffrequenciesHzEditField_2.Limits=[app.BandpassCutofffrequenciesHzEditField.Value,inf];
        end

        % Value changed function: BandpassCutofffrequenciesHzEditField_2
        function BandpassCutofffrequenciesHzEditField_2ValueChanged(app, event)
            app.BandpassCutofffrequenciesHzEditField.Limits=[0,app.BandpassCutofffrequenciesHzEditField_2.Value];
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            new_preprocess.movingaverage = app.TimeofmovingavergefiltersecondsEditField.Value;
            new_preprocess.lowpass = app.LowpassCutofffrequencyHzEditField.Value;
            if app.BandpassCutofffrequenciesHzEditField.Value==0 && app.BandpassCutofffrequenciesHzEditField_2.Value==0
                new_preprocess.bandpass=[];
            else
                new_preprocess.bandpass = [app.BandpassCutofffrequenciesHzEditField.Value,app.BandpassCutofffrequenciesHzEditField_2.Value];
            end
            new_preprocess.timewindow = app.TimeWindowsecondsEditField.Value;
            save("new_preprocess.mat","new_preprocess");
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'login.png');
            app.EditECGPreprocessingSettingsLabel = uilabel(app.UIFigure);
            app.EditECGPreprocessingSettingsLabel.Text = 'Edit ECG Preprocessing Settings';
            app.TimeofmovingavergefiltersecondsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeofmovingavergefiltersecondsEditFieldLabel.Text = 'Time of moving averge filter (seconds)';
            app.TimeofmovingavergefiltersecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeofmovingavergefiltersecondsEditField.Limits = [0 Inf];
            app.TimeofmovingavergefiltersecondsEditField.Value = 0.1;
            app.LowpassCutofffrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassCutofffrequencyHzEditFieldLabel.Text = 'Lowpass Cut-off frequency (Hz) ';
            app.LowpassCutofffrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassCutofffrequencyHzEditField.Limits = [0 Inf];
            app.LowpassCutofffrequencyHzEditField.Value = 30;
            app.BandpassCutofffrequenciesHzEditFieldLabel = uilabel(app.UIFigure);
            app.BandpassCutofffrequenciesHzEditFieldLabel.Text = 'Bandpass Cut-off frequencies (Hz)';
            app.BandpassCutofffrequenciesHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandpassCutofffrequenciesHzEditField.Limits = [0 Inf];
            app.BandpassCutofffrequenciesHzEditField.ValueChangedFcn = createCallbackFcn(app, @BandpassCutofffrequenciesHzEditFieldValueChanged, true);
            app.BandpassCutofffrequenciesHzEditField.Value = 5;
            app.TimeWindowsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeWindowsecondsEditFieldLabel.Text = 'Time Window (seconds)';
            app.TimeWindowsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeWindowsecondsEditField.Value = 0.2;
            app.BandpassCutofffrequenciesHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BandpassCutofffrequenciesHzEditField_2.Limits = [0 Inf];
            app.BandpassCutofffrequenciesHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @BandpassCutofffrequenciesHzEditField_2ValueChanged, true);
            app.BandpassCutofffrequenciesHzEditField_2.Value = 25;
            app.movingaverage_info = uibutton(app.UIFigure, 'push');
            app.movingaverage_info.ButtonPushedFcn = createCallbackFcn(app, @movingaverage_infoButtonPushed, true);
            app.movingaverage_info.Text = '';
            app.lowpass_info = uibutton(app.UIFigure, 'push');
            app.lowpass_info.ButtonPushedFcn = createCallbackFcn(app, @lowpass_infoButtonPushed, true);
            app.lowpass_info.Text = '';
            app.bandpass_info = uibutton(app.UIFigure, 'push');
            app.bandpass_info.ButtonPushedFcn = createCallbackFcn(app, @bandpass_infoButtonPushed, true);
            app.bandpass_info.Text = '';
            app.timewindow_info = uibutton(app.UIFigure, 'push');
            app.timewindow_info.ButtonPushedFcn = createCallbackFcn(app, @timewindow_infoButtonPushed, true);
            app.timewindow_info.Text = '';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end